classdef ViewDpxModelData < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure         matlab.ui.Figure
		FileMenu         matlab.ui.container.Menu
		Menu             matlab.ui.container.Menu
		xMenu            matlab.ui.container.Menu
		Menu2            matlab.ui.container.Menu
		Toolbar          matlab.ui.container.Toolbar
		PushTool         matlab.ui.container.toolbar.PushTool
		GridLayout       matlab.ui.container.GridLayout
		Panel            matlab.ui.container.Panel
		Label            matlab.ui.control.Label
		TabGroup         matlab.ui.container.TabGroup
		Tab              matlab.ui.container.Tab
		GridLayout2      matlab.ui.container.GridLayout
		GridLayout3      matlab.ui.container.GridLayout
		ListBox          matlab.ui.control.ListBox
		UITable          matlab.ui.control.Table
		ButtonGroup      matlab.ui.container.ButtonGroup
		IaFwButton       matlab.ui.control.RadioButton
		IdIqButton       matlab.ui.control.RadioButton
		UIAxes           matlab.ui.control.UIAxes
		MapTab           matlab.ui.container.Tab
		GrdMapData       matlab.ui.container.GridLayout
		GrdMapB          matlab.ui.container.GridLayout
		SldIdB           matlab.ui.control.Slider
		Label_10         matlab.ui.control.Label
		SldIqB           matlab.ui.control.Slider
		Label_9          matlab.ui.control.Label
		FigMapB          matlab.ui.control.UIAxes
		GrdMapA          matlab.ui.container.GridLayout
		SldIdA           matlab.ui.control.Slider
		Label_8          matlab.ui.control.Label
		SldIqA           matlab.ui.control.Slider
		Label_7          matlab.ui.control.Label
		FigMapA          matlab.ui.control.UIAxes
		GrdMapSets       matlab.ui.container.GridLayout
		GrdMapAxis       matlab.ui.container.GridLayout
		lbIqAxisB        matlab.ui.control.ListBox
		IqBListBoxLabel  matlab.ui.control.Label
		lbIdAxisB        matlab.ui.control.ListBox
		IdBListBoxLabel  matlab.ui.control.Label
		lbIqAxisA        matlab.ui.control.ListBox
		IqALabel         matlab.ui.control.Label
		lbIdAxisA        matlab.ui.control.ListBox
		IdAALabel        matlab.ui.control.Label
		cmbFigTypes      matlab.ui.control.DropDown
		Label_5          matlab.ui.control.Label
		cmbDspTypes      matlab.ui.control.DropDown
		Label_4          matlab.ui.control.Label
		cmbAxTypes       matlab.ui.control.DropDown
		Label_3          matlab.ui.control.Label
		cmbDatNames      matlab.ui.control.DropDown
		Label_2          matlab.ui.control.Label
	end

	
	properties (Access = private)
		DspMapSettings % Description
		ModelData	MbdModel 
	end
	
	properties (Constant)
		eps = 1.e-5;
		dne = round(-log10(eps));
		DataNames =["Torque","Current","CoitFlux" ...
						,"PhaseVoltage","LineVoltage"]; % JMagデータ名
	end
	
	methods (Access = private)
		% Mapデータ設定の更新
		function UpdDspMapSetting(app, isGui)
			if nargin < 2
				isGui = false;
			end
			sets = app.DspMapSettings;
			if isGui
				app.cmbDatNames.Value = sets.dName;
				app.cmbAxTypes. Value = sets.axTyp;
				app.cmbDspTypes.Value = sets.dspTyp;
				app.cmbFigTypes.Value = sets.figTyp;
			else
				sets.dName  = app.cmbDatNames.Value;
				sets.axTyp  = app.cmbAxTypes.Value;
				sets.dspTyp = app.cmbDspTypes.Value;
				sets.figTyp = app.cmbFigTypes.Value;
			end
		end
	
		
		function UpdFigMap(app)
			mdl = app.ModelData;
			sets = app.DspMapSettings;

			in = sets.dName;
			tdm = mdl.map{in};
			

		end
		
		function InitMapSetting(app)
			mdl = app.ModelData;
			if isempty(mdl)
				return;
			end

			% tcs = {app.lbIdAxisA, app.lbIqAxisA ...
				% 	, app.lbIdAxisB, app.lbIqAxisB};
			% for i=1:length(tcs)
			% 	tgt = tcs{i};
			% 	tgt.Items = string(mdl.vIds);
			% 	tgt.ItemsData = 1:length(mdl.vIds);
			% end

			vs = mdl.vIds; ns=length(vs); ss = string(vs);
			minv = min(vs); maxv = max(vs);

			app.lbIdAxisA.Items = ss(end:-1:1);
			app.lbIdAxisA.ItemsData = ns:-1:1;
			idx = find(abs(vs)<app.eps, 1);
			if isempty(idx) || idx<=0
				idx = 0;
			end
			app.lbIdAxisA.Value = idx;
			app.lbIdAxisA.UserData = struct('dst', app.SldIdA, 'k', -1);

			app.SldIdA.Limits =[-maxv, -minv];
			app.SldIdA.MajorTicks = -vs';
			app.SldIdA.MinorTicks = [];
			app.SldIdA.MajorTickLabels= {};
			app.SldIdA.Value = -vs(idx);
			app.SldIdA.UserData = struct('dst', app.lbIdAxisA , 'k', -1);

			app.lbIdAxisB.Items = ss(end:-1:1);
			app.lbIdAxisB.ItemsData = ns:-1:1;
			idx = find(abs(vs)<app.eps, 1);
			if isempty(idx) || idx<=0
				idx = 0;
			end
			app.lbIdAxisB.Value = idx;
			app.lbIdAxisB.UserData = struct('dst', app.SldIdB , 'k', -1);

			app.SldIdB.Limits =[-maxv, -minv];
			app.SldIdB.MajorTicks = -vs';
			app.SldIdB.MinorTicks = [];
			app.SldIdB.MajorTickLabels= {};
			app.SldIdB.Value = -vs(idx);
			app.SldIdB.UserData = -1;
			app.SldIdB.UserData = struct('dst', app.lbIdAxisA, 'k', -1);

			vs = mdl.vIqs; ns=length(vs); ss = string(vs);
			minv = min(vs); maxv = max(vs);

			app.lbIqAxisA.Items = ss;
			app.lbIqAxisA.ItemsData = 1:ns;
			idx = find(abs(vs)<app.eps, 1);
			if isempty(idx)
				idx = 0;
			end
			app.lbIqAxisA.Value = idx;
			app.lbIqAxisA.UserData = struct('dst', app.SldIqA ...;
											, 'k', -1, 'val', vs);

			app.SldIqA.Limits =[minv, maxv];
			app.SldIqA.MajorTicks = vs';
			app.SldIqA.MinorTicks = [];
			app.SldIqA.MajorTickLabels= {};
			app.SldIqA.Value = vs(idx);
			app.SldIqA.UserData = 1;
			app.SldIqA.UserData = struct('dst', app.lbIqAxisA, 'k', 1);

			app.lbIqAxisB.Items = ss;
			app.lbIqAxisB.ItemsData = 1:ns;
			idx = find(abs(vs)<app.eps, 1);
			if isempty(idx) || idx<=0
				idx = 0;
			end
			app.lbIqAxisB.Value = idx;
			app.lbIqAxisB.UserData = struct('dst', app.SldIqB, 'k', 1);

			app.SldIqB.Limits =[minv, maxv];
			app.SldIqB.MajorTicks = vs';
			app.SldIqB.MinorTicks = [];
			app.SldIqB.MajorTickLabels= {};
			app.SldIqB.Value =vs(idx);
			app.SldIqB.UserData = 1;
			app.SldIqB.UserData = struct('dst', app.lbIqAxisB, 'k', 1);

		end
	end
	

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			fprintf(">>>>> Now loading map\n")
			mdl = evalin('base', "mdl");
			app.ModelData = mdl;
			% load
			fprintf("<<<<< End.\n")
			app.TabGroup.SelectedTab = app.MapTab;
			app.DspMapSettings = struct("dName", 3,"a5xTyp", 1 ...
							,"dspTyp", 1,"figTyp", 1); % Figure表示タイプ
			app.InitMapSetting();
			app.UpdFigMap();

		end

		% Value changed function: cmbDatNames
		function cmbDatNamesValueChanged(app, event)
			app.DspMapSettings.dName = app.cmbDatNames.Value;
			app.UpdFigMap();
		end

		% Value changed function: cmbAxTypes
		function cmbAxTypesValueChanged(app, event)
			app.DspMapSettings.axTyp = app.cmbAxTypes.Value;
			app.UpdFigMap();		
		end

		% Value changed function: cmbDspTypes
		function cmbDspTypesValueChanged(app, event)
			app.DspMapSettings.dspTyp = app.cmbDspTypes.Value;
			app.UpdFigMap();		
		end

		% Value changed function: cmbFigTypes
		function cmbFigTypesValueChanged(app, event)
			app.DspMapSettings.figTyp = app.cmbFigTypes.Value;
			app.UpdFigMap();
		end

		% Value changing function: SldIdA, SldIdB, SldIqA, SldIqB
		function SldValueChanging(app, event)
			v0 = event.Value;
			tgt = event.Source;
			if isa(tgt, 'matlab.ui.control.Slider')
				vs = tgt.MajorTicks;
				[~, idx] = min(abs(vs-v0));
				tgt.Value = vs(idx);
			end
			fprintf("Slide Value Changing\n");
		end

		% Value changed function: SldIdA, SldIdB, SldIqA, SldIqB
		function ValueChanged(app, event)
			v0 = event.Value;
			tgt = event.Source;
			tv = tgt.Value;
			dst = tgt.UserData.dst;
			k = tgt.UserData.k;
			if isa(tgt, 'matlab.ui.control.Slider')
				vs = tgt.MajorTicks;
				[~, idx] = min(abs(vs-v0));
				tgt.Value = vs(idx);
			end
			fprintf("Value Changed\n");
		end

		% Value changed function: lbIdAxisA, lbIdAxisB, lbIqAxisA, 
		% ...and 1 other component
		function AxisValueChanged(app, event)
			fprintf("Axis Value Changed\n");
			value = app.lbIqAxisB.Value;
			
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.FileMenu = uimenu(app.UIFigure);
			app.FileMenu.Text = 'Fil&e';
			app.Menu = uimenu(app.FileMenu);
			app.Menu.Accelerator = 'o';
			app.Menu.Text = '開く';
			app.xMenu = uimenu(app.FileMenu);
			app.xMenu.Separator = 'on';
			app.xMenu.Accelerator = 'x';
			app.xMenu.Text = '終了(&x)';
			app.Menu2 = uimenu(app.UIFigure);
			app.Menu2.Text = 'Menu2';
			app.Toolbar = uitoolbar(app.UIFigure);
			app.PushTool = uipushtool(app.Toolbar);
			app.GridLayout = uigridlayout(app.UIFigure);
			app.TabGroup = uitabgroup(app.GridLayout);
			app.Tab = uitab(app.TabGroup);
			app.Tab.Title = 'Tab';
			app.GridLayout2 = uigridlayout(app.Tab);
			app.UIAxes = uiaxes(app.GridLayout2);
			title(app.UIAxes, 'Title')
			xlabel(app.UIAxes, 'Id [A]')
			ylabel(app.UIAxes, 'Iq [A]')
			zlabel(app.UIAxes, 'Z')
			app.ButtonGroup = uibuttongroup(app.GridLayout2);
			app.IdIqButton = uiradiobutton(app.ButtonGroup);
			app.IdIqButton.Text = 'Id-Iq';
			app.IdIqButton.Value = true;
			app.IaFwButton = uiradiobutton(app.ButtonGroup);
			app.IaFwButton.Text = 'Ia-Fw';
			app.UITable = uitable(app.GridLayout2);
			app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
			app.UITable.RowName = {};
			app.GridLayout3 = uigridlayout(app.GridLayout2);
			app.ListBox = uilistbox(app.GridLayout3);
			app.MapTab = uitab(app.TabGroup);
			app.MapTab.Title = 'Map';
			app.GrdMapData = uigridlayout(app.MapTab);
			app.GrdMapSets = uigridlayout(app.GrdMapData);
			app.Label_2 = uilabel(app.GrdMapSets);
			app.Label_2.Text = 'データ：';
			app.cmbDatNames = uidropdown(app.GrdMapSets);
			app.cmbDatNames.Items = {'トルク', '磁束', '相電圧', '線間電圧', '電流'};
			app.cmbDatNames.ItemsData = {'1', '3', '4', '5', '2'};
			app.cmbDatNames.ValueChangedFcn = createCallbackFcn(app, @cmbDatNamesValueChanged, true);
			app.cmbDatNames.Value = '3';
			app.Label_3 = uilabel(app.GrdMapSets);
			app.Label_3.Text = '軸/相：';
			app.cmbAxTypes = uidropdown(app.GrdMapSets);
			app.cmbAxTypes.Items = {'dq軸', '３相'};
			app.cmbAxTypes.ItemsData = {'1', '2'};
			app.cmbAxTypes.ValueChangedFcn = createCallbackFcn(app, @cmbAxTypesValueChanged, true);
			app.cmbAxTypes.Value = '1';
			app.Label_4 = uilabel(app.GrdMapSets);
			app.Label_4.Text = '表示：';
			app.cmbDspTypes = uidropdown(app.GrdMapSets);
			app.cmbDspTypes.Items = {'全て', 'd軸', 'q軸', 'Vz'};
			app.cmbDspTypes.ItemsData = {'0', '1', '2', '3'};
			app.cmbDspTypes.ValueChangedFcn = createCallbackFcn(app, @cmbDspTypesValueChanged, true);
			app.cmbDspTypes.Value = '1';
			app.Label_5 = uilabel(app.GrdMapSets);
			app.Label_5.Text = '表示：';
			app.cmbFigTypes = uidropdown(app.GrdMapSets);
			app.cmbFigTypes.Items = {'Map', '電気角'};
			app.cmbFigTypes.ItemsData = [1 2];
			app.cmbFigTypes.ValueChangedFcn = createCallbackFcn(app, @cmbFigTypesValueChanged, true);
			app.cmbFigTypes.Value = 1;
			app.GrdMapAxis = uigridlayout(app.GrdMapSets);
			app.IdAALabel = uilabel(app.GrdMapAxis);
			app.IdAALabel.Text = 'Id A ';
			app.lbIdAxisA = uilistbox(app.GrdMapAxis);
			app.lbIdAxisA.Items = {''};
			app.lbIdAxisA.ValueChangedFcn = createCallbackFcn(app, @AxisValueChanged, true);
			app.lbIdAxisA.Value = '';
			app.IqALabel = uilabel(app.GrdMapAxis);
			app.IqALabel.Text = 'Iq A ';
			app.lbIqAxisA = uilistbox(app.GrdMapAxis);
			app.lbIqAxisA.Items = {''};
			app.lbIqAxisA.ValueChangedFcn = createCallbackFcn(app, @AxisValueChanged, true);
			app.lbIqAxisA.Value = '';
			app.IdBListBoxLabel = uilabel(app.GrdMapAxis);
			app.IdBListBoxLabel.Text = 'Id B';
			app.lbIdAxisB = uilistbox(app.GrdMapAxis);
			app.lbIdAxisB.Items = {''};
			app.lbIdAxisB.ValueChangedFcn = createCallbackFcn(app, @AxisValueChanged, true);
			app.lbIdAxisB.Value = '';
			app.IqBListBoxLabel = uilabel(app.GrdMapAxis);
			app.IqBListBoxLabel.Text = 'Iq B';
			app.lbIqAxisB = uilistbox(app.GrdMapAxis);
			app.lbIqAxisB.Items = {''};
			app.lbIqAxisB.ValueChangedFcn = createCallbackFcn(app, @AxisValueChanged, true);
			app.lbIqAxisB.Value = '';
			app.GrdMapA = uigridlayout(app.GrdMapData);
			app.FigMapA = uiaxes(app.GrdMapA);
			title(app.FigMapA, 'Side-A')
			xlabel(app.FigMapA, 'X')
			ylabel(app.FigMapA, 'Y')
			zlabel(app.FigMapA, 'Z')
			app.Label_7 = uilabel(app.GrdMapA);
			app.Label_7.Text = '';
			app.SldIqA = uislider(app.GrdMapA);
			app.SldIqA.MajorTicks = [];
			app.SldIqA.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.SldIqA.ValueChangingFcn = createCallbackFcn(app, @SldValueChanging, true);
			app.SldIqA.MinorTicks = [];
			app.Label_8 = uilabel(app.GrdMapA);
			app.Label_8.Text = '';
			app.SldIdA = uislider(app.GrdMapA);
			app.SldIdA.MajorTicks = [];
			app.SldIdA.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.SldIdA.ValueChangingFcn = createCallbackFcn(app, @SldValueChanging, true);
			app.SldIdA.MinorTicks = [];
			app.GrdMapB = uigridlayout(app.GrdMapData);
			app.FigMapB = uiaxes(app.GrdMapB);
			title(app.FigMapB, 'Side-B')
			xlabel(app.FigMapB, 'X')
			ylabel(app.FigMapB, 'Y')
			zlabel(app.FigMapB, 'Z')
			app.Label_9 = uilabel(app.GrdMapB);
			app.Label_9.Text = '';
			app.SldIqB = uislider(app.GrdMapB);
			app.SldIqB.MajorTicks = [];
			app.SldIqB.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.SldIqB.ValueChangingFcn = createCallbackFcn(app, @SldValueChanging, true);
			app.SldIqB.MinorTicks = [];
			app.Label_10 = uilabel(app.GrdMapB);
			app.Label_10.Text = '';
			app.SldIdB = uislider(app.GrdMapB);
			app.SldIdB.MajorTicks = [];
			app.SldIdB.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
			app.SldIdB.ValueChangingFcn = createCallbackFcn(app, @SldValueChanging, true);
			app.SldIdB.MinorTicks = [];
			app.Panel = uipanel(app.GridLayout);
			app.Label = uilabel(app.Panel);
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = ViewDpxModelData

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end